classdef classifierApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AppdeClasificadoresUIFigure     matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        CoordenadaXEditFieldLabel       matlab.ui.control.Label
        CoordenadaXEditField            matlab.ui.control.NumericEditField
        CoordenadaYEditFieldLabel       matlab.ui.control.Label
        CoordenadaYEditField            matlab.ui.control.NumericEditField
        DispersinEditFieldLabel         matlab.ui.control.Label
        DispersinEditField              matlab.ui.control.NumericEditField
        NmerodeClasesEditFieldLabel     matlab.ui.control.Label
        NmerodeClasesEditField          matlab.ui.control.NumericEditField
        CrearButton                     matlab.ui.control.Button
        CrearClaseButton                matlab.ui.control.Button
        XPruebaEditFieldLabel           matlab.ui.control.Label
        XPruebaEditField                matlab.ui.control.NumericEditField
        YPruebaEditFieldLabel           matlab.ui.control.Label
        YPruebaEditField                matlab.ui.control.NumericEditField
        MtodoListBoxLabel               matlab.ui.control.Label
        MtodoListBox                    matlab.ui.control.ListBox
        MiembrosporClaseEditFieldLabel  matlab.ui.control.Label
        MiembrosporClaseEditField       matlab.ui.control.NumericEditField
        ProbarButton                    matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
    end

    
    properties (Access = private)
        
        randR = 1       % The rightmost end of the random range.
        randL = -1      % The leftmost end of the random range.
        members         % The number of members in each class.
        classIndex      % The currently created class index. 
        dimension = 2   % The dimension of the vectors.
        testCount       % The current test count.
        classes         % The number of classes.
        means           % The class means.
        classifiers     % The classes.
        
    end
    
    methods (Access = private)
    
        function CreateClass(app, k, x, y, r)
            classMembers = rand(app.dimension, app.members) .* (app.randR - app.randL);
            classMembers = (classMembers + app.randL) .* r;
            classMembers(1, :) = classMembers(1, :) + x;
            classMembers(2, :) = classMembers(2, :) + y;
            app.classifiers{k} = classMembers;
            app.means(:, k) = mean(app.classifiers{k}, 2);
            scatter(app.UIAxes, app.classifiers{k}(1, :), app.classifiers{k}(2, :), 'filled', 'DisplayName', strcat('C', num2str(k)));
        end
    
        function class = TestVectors(app, testVectors)
            option = str2double(app.MtodoListBox.Value);
            if option == 1
                [ class ] = app.Euclidean(testVectors);
            elseif option == 2
                [ class ] = app.Mahalanobis(testVectors);
            elseif option == 3
                [ class ] = app.MaxProbability(testVectors);
            elseif option == 4
                %k = inputdlg({ 'Ingresa el valor de K' }, 'K-Nearest Neighbors');
                knnN = 3;
                [ class ] = app.KNearestNeighbors(testVectors, knnN);
            end
            %scatter(app.UIAxes, x, y, 'LineWidth', 1.5, 'DisplayName', strcat('X', num2str(app.testCount))); 
            %app.testCount = app.testCount + 1;
        end
    
        function Resubstitution(app)
            confussion = zeros(app.classes);
            
            for k = 1 : app.classes
                class = app.TestVectors(app.classifiers{k});
                [ ~, n ] = size(class);
                for c = 1 : n
                    confussion(k, class(c)) = confussion(k, class(c)) + 1;
                end
            end
            
            accuracy = zeros(1, app.classes);
            
            for k = 1 : app.classes
                s = sum(confussion(k, :));
                accuracy(k) = confussion(k, k) / s * 100;
            end
            
            for k = 2 : app.classes
                accuracy(k) = accuracy(k) + accuracy(k - 1);
            end
            
            for k = 1 : app.classes
                accuracy(k) = accuracy(k) / k;
            end
            
            plot(app.UIAxes, 1 : 1 : app.classes, accuracy, 'DisplayName', 'Resustitución');
        end
    
%         function CrossValidation(app)
%             accuracy = zeros(1, app.classes);
%             experiments = 25;
%             
%             for it = 1 : experiments
%                 confussion = zeros(app.classes);
%                 
%                 for k = 1 : app.classes
%                     cluster = app.classifiers{k};
%                     [ ~, n ] = size(cluster);
%                     indices = randperm(n);
%                     m = floor(n / 2);
%                     
%                     app.classifiers{k} = cluster(:, indices(m + 1 : n));
%                     class = app.TestVectors(cluster(:, indices(1 : m)));
%                     [ ~, n ] = size(class);
%                     
%                     for c = 1 : n
%                         confussion(k, class(c)) = confussion(k, class(c)) + 1;
%                     end
%                     
%                     app.classifiers{k} = cluster;
%                 end
%                 
%                 for k = 1 : app.classes
%                     s = sum(confussion(k, :));
%                     accuracy(k) = accuracy(k) + confussion(k, k) / s  * 100 ;
%                 end
%             end
%             
%             accuracy(:, :) = accuracy(:, :) / experiments;
%             
%             for k = 2 : app.classes
%                 accuracy(k) = accuracy(k) + accuracy(k - 1);
%             end
%             
%             for k = 1 : app.classes
%                 accuracy(k) = accuracy(k) / k;
%             end
%             
%             plot(app.UIAxes, 1 : 1 : app.classes, accuracy, 'DisplayName', 'Cross Validation');
%         end

        function CrossValidation(app)
            accuracy = zeros(1, app.classes);
            clusters = app.classifiers;
            experiments = 25;
            
            for it = 1 : experiments
                testVectors = cell(1, app.classes);
                
                for k = 1 : app.classes
                    cluster = clusters{k};
                    [ ~, n ] = size(cluster);
                    indices = randperm(n);
                    m = floor(n / 2);
                    
                    app.classifiers{k} = cluster(:, indices(m + 1 : n));
                    testVectors{k} = cluster(:, indices(1 : m));
                end
                
                confussion = zeros(app.classes);
                
                for k = 1 : app.classes
                    class = app.TestVectors(testVectors{k});
                    [ ~, n ] = size(class);
                    for c = 1 : n
                        confussion(k, class(c)) = confussion(k, class(c)) + 1;
                    end
                end
                
                for k = 1 : app.classes
                    s = sum(confussion(k, :));
                    accuracy(k) = accuracy(k) + confussion(k, k) / s  * 100 ;
                end
            end
            
            app.classifiers = clusters;
            
            accuracy(:, :) = accuracy(:, :) / experiments;
            
            for k = 2 : app.classes
                accuracy(k) = accuracy(k) + accuracy(k - 1);
            end
            
            for k = 1 : app.classes
                accuracy(k) = accuracy(k) / k;
            end
            
            plot(app.UIAxes, 1 : 1 : app.classes, accuracy, 'DisplayName', 'Cross Validation');
        end
    
        function LeaveOneOut(app)
            confussion = zeros(app.classes);
            
            for k = 1 : app.classes
                cluster = app.classifiers{k};
                [ ~, n ] = size(cluster);
                for x = 1 : n
                    newCluster = [];
                    if x > 1
                        newCluster = [ newCluster cluster(:, 1 : n - 1)];
                    end
                    if x < n
                        newCluster = [ newCluster cluster(:, x + 1 : n)];
                    end
                    app.classifiers{k} = newCluster;
                    class = app.TestVectors(cluster(:, x));
                    confussion(k, class(1)) = confussion(k, class(1)) + 1;
                end
                app.classifiers{k} = cluster;
            end
            
            accuracy = zeros(1, app.classes);
            
            for k = 1 : app.classes
                s = sum(confussion(k, :));
                accuracy(k) = confussion(k, k) / s * 100;
            end
            
            for k = 2 : app.classes
                accuracy(k) = accuracy(k) + accuracy(k - 1);
            end
            
            for k = 1 : app.classes
                accuracy(k) = accuracy(k) / k;
            end
            
            plot(app.UIAxes, 1 : 1 : app.classes, accuracy, 'DisplayName', 'Leave One Out');
        end
    
        function TestAccuracy(app)
            app.DisableTestGroup();
            cla(app.UIAxes);
            app.Resubstitution();
            app.CrossValidation();
            app.LeaveOneOut();
            xlim(app.UIAxes, 'auto');
            ylim(app.UIAxes, 'auto');
            app.EnableTestGroup();
        end
    
        function PrintOutput(app, vector, class, method, outputVector)
            output = [ 'El vector ' mat2str(vector) ];
            output = [ output ' pertenece a la clase ' num2str(class) ];
            output = [ output ' por medio de ' method ];
            app.UpdateTable(outputVector);
            app.Label.Text = output;
        end
    
        function [ minClass ] = Mahalanobis(app, testVectors)
            % Minimum Mahalanobis classification algorithm.
            
            [ ~, n ] = size(testVectors);
            
            distances = zeros(1, n);
            distances(:, :) = Inf;
            
            minClass = zeros(1, n);
            
            for k = 1 : app.classes
                [ ~, elements ] = size(app.classifiers{k});
                cmean = mean(app.classifiers{k}, 2);
                m = app.classifiers{k} - cmean;
                sigma = (m * m') ./ elements;
                for x = 1 : n
                    testVector = testVectors(:, x);
                    % Compute Mahalanobis distance.
                    m = testVector - cmean;
                    dist = (m' / sigma) * m;
                    if distances(x) > dist
                        % Minimize distance.
                        distances(x) = dist;
                        % Store the new closest class.
                        minClass(x) = k;
                    end
                end
            end
                
        end
    
        function [ class ] = MaxProbability(app, testVectors)
            % Maximum Probability classification algorithm.
            
            sigma = zeros(app.dimension, app.dimension, app.classes);
            
            cmeans = zeros(app.dimension, app.classes);
            
            [ ~, n ] = size(testVectors);
            class = zeros(1, n);
            
            for k = 1 : app.classes
                [ ~, elements ] = size(app.classifiers{k});
                cmeans(:, k) = mean(app.classifiers{k}, 2);
                m = app.classifiers{k} - cmeans(:, k);
                sigma(:, :, k) = (m * m') ./ elements;
            end
            
            den = (2 * pi()) ^ app.dimension;
            
            for x = 1 : n
                testVector = testVectors(:, x);
                p = zeros(1, app.classes);
                accumulated = 0;
                
                for k = 1 : app.classes
                    % Compute Probability.
                    m = testVector - cmeans(:, k);
                    prob = exp(- ((m' / sigma(:, :, k)) * m) / 2);
                    prob = prob / sqrt(det(sigma(:, :, k)) * den);
                    accumulated = accumulated + prob;
                    p(k) = prob;
                end
                
                % Maximum probability starts at -infinity.
                maxProbability = -Inf;
                
                for k = 1 : app.classes
                    p(k) = p(k) / accumulated;
                    if maxProbability < p(k)
                        % Maximize probability.
                        maxProbability = p(k);
                        % Store the new closest class.
                        class(x) = k;
                    end
                end
            end
            
        end
    
        function [ minClass ] = Euclidean(app, testVectors)
            % Minimum Distance classification algorithm.
            
            [ ~, n ] = size(testVectors);
            
            distances = zeros(1, n);
            distances(:, :) = Inf;
            
            minClass = zeros(1, n);
            
            for k = 1 : app.classes
                cmean = mean(app.classifiers{k}, 2);
                for x = 1 : n
                    testVector = testVectors(:, x);
                    dist = norm(cmean - testVector);
                    if distances(x) > dist
                        % Minimize distance.
                        distances(x) = dist;
                        % Store the new closest class.
                        minClass(x) = k;
                    end
                end
            end
            
        end
    
        function [ class ] = KNearestNeighbors(app, testVectors, knn)
            % Minimum Distance classification algorithm.
            
            elements = 0;
            
            for k = 1 : app.classes
                [ ~, s ] = size(app.classifiers{k});
                elements = elements + s;
            end
            
            [ ~, n ] = size(testVectors);
            class = zeros(1, n);
            
            for x = 1 : n
                distances = zeros(1, elements);
                bucket = zeros(1, app.classes);
                id = zeros(1, elements);
                
                testVector = testVectors(:, x);
                
                index = 0;
                
                for k = 1 : app.classes
                    [ ~, s ] = size(app.classifiers{k});
                    for l = 1 : s
                        index = index + 1;
                        distances(index) = norm(app.classifiers{k}(:, l) - testVector);
                        id(index) = k;
                    end
                end
                
                [ ~, indices ] = sort(distances);
                last = zeros(1, app.classes);
                id = id(indices);
                
                for k = 1 : knn
                    bucket(id(k)) = bucket(id(k)) + 1;
                    last(id(k)) = k;
                end
                
                maxBucket = -Inf;
                minLast = Inf;
                
                for k = 1 : app.classes
                    if bucket(k) > maxBucket
                        maxBucket = bucket(k);
                        minLast = last(k);
                        class(x) = k;
                    elseif bucket(k) == maxBucket
                        if last(k) < minLast
                           minLast = last(k);
                           class(x) = k;
                        end
                    end
                end
            end
            
        end
        
        function UpdateTable(app, distances)
            classLabels = 1 : 1 : app.classes;
            app.UITable.Data = table(classLabels', distances);
        end
        
        function DisableClassesGroup(app)
            app.MiembrosporClaseEditField.Enable = 'off';
            app.NmerodeClasesEditField.Enable = 'off';
            app.CrearButton.Enable = 'off';
        end
        
        function EnableClassesGroup(app)
            app.MiembrosporClaseEditField.Enable = 'on';
            app.NmerodeClasesEditField.Enable = 'on';
            app.CrearButton.Enable = 'on';
        end
        
        function DisableClassGroup(app)
            app.CoordenadaXEditField.Enable = 'off';
            app.CoordenadaYEditField.Enable = 'off';
            app.DispersinEditField.Enable = 'off';
            app.CrearClaseButton.Enable = 'off';
        end
        
        function EnableClassGroup(app)
            app.CoordenadaXEditField.Enable = 'on';
            app.CoordenadaYEditField.Enable = 'on';
            app.DispersinEditField.Enable = 'on';
            app.CrearClaseButton.Enable = 'on';
        end
        
        function DisableTestGroup(app)
            %app.XPruebaEditField.Enable = 'off';
            %app.YPruebaEditField.Enable = 'off';
            app.MtodoListBox.Enable = 'off';
            app.ProbarButton.Enable = 'off';
        end
        
        function EnableTestGroup(app)
            %app.XPruebaEditField.Enable = 'on';
            %app.YPruebaEditField.Enable = 'on';
            app.MtodoListBox.Enable = 'on';
            app.ProbarButton.Enable = 'on';
        end
    
    end
    

    methods (Access = private)

        % Button pushed function: CrearButton
        function CrearButtonPushed(app, event)
            app.members = app.MiembrosporClaseEditField.Value;
            app.classes = app.NmerodeClasesEditField.Value;
            app.classifiers = cell(1, app.dimension);
            app.means = zeros(app.dimension, app.classes);
            app.DisableClassesGroup();
            app.DisableTestGroup();
            app.EnableClassGroup();
            app.Label.Text = '';
            app.classIndex = 1;
            app.testCount = 1;
            cla(app.UIAxes);
        end

        % Button pushed function: CrearClaseButton
        function CrearClaseButtonPushed(app, event)
            x = app.CoordenadaXEditField.Value;
            y = app.CoordenadaYEditField.Value;
            r = app.DispersinEditField.Value;
            app.CreateClass(app.classIndex, x, y, r);
            app.classIndex = app.classIndex + 1;
            if app.classIndex > app.classes
                scatter(app.UIAxes, app.means(1, :), app.means(2, :), 'filled', 'DisplayName', 'Medias');
                legend(app.UIAxes, 'show');
                app.EnableClassesGroup();
                app.DisableClassGroup();
                app.EnableTestGroup();
                return;
            end
        end

        % Button pushed function: ProbarButton
        function ProbarButtonPushed(app, event)
            %x = app.XPruebaEditField.Value;
            %y = app.YPruebaEditField.Value;
            app.TestAccuracy();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AppdeClasificadoresUIFigure = uifigure;
            app.AppdeClasificadoresUIFigure.Name = 'App de Clasificadores';
            app.UIAxes = uiaxes(app.AppdeClasificadoresUIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.CoordenadaXEditFieldLabel = uilabel(app.AppdeClasificadoresUIFigure);
            app.CoordenadaXEditFieldLabel.Text = 'Coordenada X';
            app.CoordenadaXEditField = uieditfield(app.AppdeClasificadoresUIFigure, 'numeric');
            app.CoordenadaXEditField.Enable = 'off';
            app.CoordenadaYEditFieldLabel = uilabel(app.AppdeClasificadoresUIFigure);
            app.CoordenadaYEditFieldLabel.Text = 'Coordenada Y';
            app.CoordenadaYEditField = uieditfield(app.AppdeClasificadoresUIFigure, 'numeric');
            app.CoordenadaYEditField.Enable = 'off';
            app.DispersinEditFieldLabel = uilabel(app.AppdeClasificadoresUIFigure);
            app.DispersinEditFieldLabel.Text = 'Dispersión';
            app.DispersinEditField = uieditfield(app.AppdeClasificadoresUIFigure, 'numeric');
            app.DispersinEditField.Limits = [1e-09 Inf];
            app.DispersinEditField.Enable = 'off';
            app.DispersinEditField.Value = 1e-09;
            app.NmerodeClasesEditFieldLabel = uilabel(app.AppdeClasificadoresUIFigure);
            app.NmerodeClasesEditFieldLabel.Text = 'Número de Clases';
            app.NmerodeClasesEditField = uieditfield(app.AppdeClasificadoresUIFigure, 'numeric');
            app.NmerodeClasesEditField.Limits = [1 Inf];
            app.NmerodeClasesEditField.RoundFractionalValues = 'on';
            app.NmerodeClasesEditField.Value = 1;
            app.CrearButton = uibutton(app.AppdeClasificadoresUIFigure, 'push');
            app.CrearButton.ButtonPushedFcn = createCallbackFcn(app, @CrearButtonPushed, true);
            app.CrearButton.Text = 'Crear';
            app.CrearClaseButton = uibutton(app.AppdeClasificadoresUIFigure, 'push');
            app.CrearClaseButton.ButtonPushedFcn = createCallbackFcn(app, @CrearClaseButtonPushed, true);
            app.CrearClaseButton.Enable = 'off';
            app.CrearClaseButton.Text = 'Crear Clase';
            app.XPruebaEditFieldLabel = uilabel(app.AppdeClasificadoresUIFigure);
            app.XPruebaEditFieldLabel.Text = 'X Prueba';
            app.XPruebaEditField = uieditfield(app.AppdeClasificadoresUIFigure, 'numeric');
            app.XPruebaEditField.Enable = 'off';
            app.YPruebaEditFieldLabel = uilabel(app.AppdeClasificadoresUIFigure);
            app.YPruebaEditFieldLabel.Text = 'Y Prueba';
            app.YPruebaEditField = uieditfield(app.AppdeClasificadoresUIFigure, 'numeric');
            app.YPruebaEditField.Enable = 'off';
            app.MtodoListBoxLabel = uilabel(app.AppdeClasificadoresUIFigure);
            app.MtodoListBoxLabel.Text = 'Método';
            app.MtodoListBox = uilistbox(app.AppdeClasificadoresUIFigure);
            app.MtodoListBox.Items = {'Distancia Euclideana', 'Mahalanobis', 'Máxima Probabilidad', 'K-Nearest Neighbors'};
            app.MtodoListBox.ItemsData = {'1', '2', '3', '4'};
            app.MtodoListBox.Enable = 'off';
            app.MtodoListBox.Value = '1';
            app.MiembrosporClaseEditFieldLabel = uilabel(app.AppdeClasificadoresUIFigure);
            app.MiembrosporClaseEditFieldLabel.Text = 'Miembros por Clase';
            app.MiembrosporClaseEditField = uieditfield(app.AppdeClasificadoresUIFigure, 'numeric');
            app.MiembrosporClaseEditField.Limits = [1 Inf];
            app.MiembrosporClaseEditField.RoundFractionalValues = 'on';
            app.MiembrosporClaseEditField.Value = 1;
            app.ProbarButton = uibutton(app.AppdeClasificadoresUIFigure, 'push');
            app.ProbarButton.ButtonPushedFcn = createCallbackFcn(app, @ProbarButtonPushed, true);
            app.ProbarButton.Enable = 'off';
            app.ProbarButton.Text = 'Probar';
            app.Label = uilabel(app.AppdeClasificadoresUIFigure);
            app.Label.Text = '';
            app.UITable = uitable(app.AppdeClasificadoresUIFigure);
            app.UITable.ColumnName = {'Clase'; 'Magnitud'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
        end
    end

    methods (Access = public)

        % Construct app
        function app = classifierApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AppdeClasificadoresUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AppdeClasificadoresUIFigure)
        end
    end
end