classdef classifierApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        CoordenadaXEditFieldLabel       matlab.ui.control.Label
        CoordenadaXEditField            matlab.ui.control.NumericEditField
        CoordenadaYEditFieldLabel       matlab.ui.control.Label
        CoordenadaYEditField            matlab.ui.control.NumericEditField
        DispersinEditFieldLabel         matlab.ui.control.Label
        DispersinEditField              matlab.ui.control.NumericEditField
        NmerodeClasesEditFieldLabel     matlab.ui.control.Label
        NmerodeClasesEditField          matlab.ui.control.NumericEditField
        CrearButton                     matlab.ui.control.Button
        CrearClaseButton                matlab.ui.control.Button
        XPruebaEditFieldLabel           matlab.ui.control.Label
        XPruebaEditField                matlab.ui.control.NumericEditField
        YPruebaEditFieldLabel           matlab.ui.control.Label
        YPruebaEditField                matlab.ui.control.NumericEditField
        MtodoListBoxLabel               matlab.ui.control.Label
        MtodoListBox                    matlab.ui.control.ListBox
        MiembrosporClaseEditFieldLabel  matlab.ui.control.Label
        MiembrosporClaseEditField       matlab.ui.control.NumericEditField
        ProbarButton                    matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        ZPruebaEditFieldLabel           matlab.ui.control.Label
        ZPruebaEditField                matlab.ui.control.NumericEditField
        CoordenadaZEditFieldLabel       matlab.ui.control.Label
        CoordenadaZEditField            matlab.ui.control.NumericEditField
        UITable                         matlab.ui.control.Table
    end

    
    properties (Access = private)
        
        members         % The number of members in each class.
        classIndex      % The currently created class index. 
        dimension = 3   % The dimension of the vectors.
        testCount       % The current test count.
        classes         % The number of classes.
        means           % The class means.
        classifiers     % The classes.
        
    end
    
    methods (Access = private)
        
        function UpdateTable(app, distances)
            classLabels = 1 : 1 : app.classes;
            app.UITable.Data = table(classLabels', distances);
        end
    
        function CreateClass(app, k, x, y, z, r)
            classMembers = randn(app.dimension, app.members) .* r;
            classMembers(1, :) = classMembers(1, :) + x;
            classMembers(2, :) = classMembers(2, :) + y;
            classMembers(3, :) = classMembers(3, :) + z;
            app.classifiers(:, :, k) = classMembers;
            app.means(:, k) = mean(app.classifiers(:, :, k), 2);
            scatter3(app.UIAxes, app.classifiers(1, :, k), app.classifiers(2, :, k), app.classifiers(3, :, k), 'filled', 'DisplayName', strcat('C', num2str(k)));
        end
    
        function TestVector(app, x, y, z)
            option = str2num(app.MtodoListBox.Value);
            testVector = [ x ; y ; z ];
            if option == 1
                [ class, distances ] = app.Euclidean(testVector);
                app.PrintOutput(testVector, class, 'Distancia Euclideana', distances);  
            elseif option == 2
                [ class, distances ] = app.Mahalanobis(testVector);
                app.PrintOutput(testVector, class, 'Mahalanobis', distances);  
            elseif option == 3
                [ class, probabilities ] = app.MaxProbability(testVector);
                app.PrintOutput(testVector, class, 'Máxima Probabilidad.', probabilities);  
            end
            scatter3(app.UIAxes, x, y, z, 'LineWidth', 1.5, 'DisplayName', strcat('X', num2str(app.testCount))); 
            app.testCount = app.testCount + 1;
        end
    
        function PrintOutput(app, vector, class, method, outputVector)
            output = [ 'El vector ' mat2str(vector) ];
            output = [ output ' pertenece a la clase ' num2str(class) ];
            output = [ output ' por medio de ' method ];
            app.UpdateTable(outputVector);
            app.Label.Text = output;
        end
    
        function [ minClass, distances ] = Mahalanobis(app, testVector)
            % Minimum Mahalanobis classification algorithm.
            
            % Collect sizes.
            [ dc, elements, app.classes ] = size(app.classifiers);
            [ dt, ~ ] = size(testVector);
            [ dm, ~ ] = size(app.means); 
            
            distances = zeros(app.classes, 1);
            minClass = 0;
            
            if dc ~= dm
                return
            end
            
            if dc ~= dt
                return
            end
            
            sigma = zeros(dc, dc, app.classes);
            
            for k = 1 : app.classes
                m = app.classifiers(:, :, k) - app.means(:, k);
                sigma(:, :, k) = (m * m') ./ elements;
            end
            
            % Minimal distance starts at infinity.
            minDist = Inf;
            % Pick any class as the closest class.
            minClass = 1;
            
            for k = 1 : app.classes
                % Compute Mahalanobis distance.
                m = testVector - app.means(:, k);
                dist = (m' / sigma(:, :, k)) * m;
                distances(k, :) = dist;
                if minDist > dist
                    % Minimize distance.
                    minDist = dist;
                    % Store the new closest class.
                    minClass = k;
                end
            end
            
        end
    
        function [ class, probabilities ] = MaxProbability(app, testVector)
            % Maximum Probability classification algorithm.
            
            % Collect sizes.
            [ dc, elements, app.classes ] = size(app.classifiers);
            [ dt, ~ ] = size(testVector);
            [ dm, ~ ] = size(app.means); 
            
            probabilities = zeros(app.classes, 1);
            class = 0;
            
            if dc ~= dm
                return
            end
            
            if dc ~= dt
                return
            end
            
            sigma = zeros(dc, dc, app.classes);
            
            for k = 1 : app.classes
                m = app.classifiers(:, :, k) - app.means(:, k);
                sigma(:, :, k) = (m * m') ./ elements;
            end
            
            % Probability vector.
            den = (2 * pi()) ^ app.dimension;
            p = zeros(app.classes);
            accumulated = 0;
            
            for k = 1 : app.classes
                % Compute Probability.
                m = testVector - app.means(:, k);
                prob = exp(- ((m' / sigma(:, :, k)) * m) / 2);
                prob = prob / sqrt(det(sigma(:, :, k)) * den);
                accumulated = accumulated + prob;
                p(k) = prob;
            end
            
            % Maximum probability starts at -infinity.
            maxProbability = -Inf;
            % Pick any class as the closest class.
            class = 1;
            
            for k = 1 : app.classes
                p(k) = p(k) / accumulated;
                probabilities(k, :) = p(k);
                if maxProbability < p(k)
                    % Maximize probability.
                    maxProbability = p(k);
                    % Store the new closest class.
                    class = k;
                end
            end
            
        end
    
        function [ minClass, distances ] = Euclidean(app, testVector)
            % Minimum Distance classification algorithm.
            
            % Collect sizes.
            [ dc, ~, app.classes ] = size(app.classifiers);
            [ dt, ~ ] = size(testVector);
            [ dm, ~ ] = size(app.means); 
            
            distances = zeros(app.classes, 1);
            minClass = 0;
            
            if dc ~= dm
                return
            end
            
            if dc ~= dt
                return
            end
            
            % Minimal distance starts at infinity.
            minDist = Inf;
            % Pick any class as the closest class.
            minClass = 1;
            for k = 1 : app.classes
                % Compute distance to class mean.
                dist = norm(app.means(:, k) - testVector);
                distances(k, :) = dist;
                if minDist > dist
                    % Minimize distance.
                    minDist = dist;
                    % Store the new closest class.
                    minClass = k;
                end
            end
            
        end
        
        function DisableClassesGroup(app)
            app.MiembrosporClaseEditField.Enable = 'off';
            app.NmerodeClasesEditField.Enable = 'off';
            app.CrearButton.Enable = 'off';
        end
        
        function EnableClassesGroup(app)
            app.MiembrosporClaseEditField.Enable = 'on';
            app.NmerodeClasesEditField.Enable = 'on';
            app.CrearButton.Enable = 'on';
        end
        
        function DisableClassGroup(app)
            app.CoordenadaXEditField.Enable = 'off';
            app.CoordenadaYEditField.Enable = 'off';
            app.CoordenadaZEditField.Enable = 'off';
            app.DispersinEditField.Enable = 'off';
            app.CrearClaseButton.Enable = 'off';
        end
        
        function EnableClassGroup(app)
            app.CoordenadaXEditField.Enable = 'on';
            app.CoordenadaYEditField.Enable = 'on';
            app.CoordenadaZEditField.Enable = 'on';
            app.DispersinEditField.Enable = 'on';
            app.CrearClaseButton.Enable = 'on';
        end
        
        function DisableTestGroup(app)
            app.XPruebaEditField.Enable = 'off';
            app.YPruebaEditField.Enable = 'off';
            app.ZPruebaEditField.Enable = 'off';
            app.ProbarButton.Enable = 'off';
        end
        
        function EnableTestGroup(app)
            app.XPruebaEditField.Enable = 'on';
            app.YPruebaEditField.Enable = 'on';
            app.ZPruebaEditField.Enable = 'on';
            app.ProbarButton.Enable = 'on';
        end
    
    end
    

    methods (Access = private)

        % Button pushed function: CrearButton
        function CrearButtonPushed(app, event)
            app.members = app.MiembrosporClaseEditField.Value;
            app.classes = app.NmerodeClasesEditField.Value;
            app.classifiers = zeros(app.dimension, app.members, app.classes);
            app.means = zeros(app.dimension, app.classes);
            app.DisableClassesGroup();
            app.DisableTestGroup();
            app.EnableClassGroup();
            app.Label.Text = '';
            app.classIndex = 1;
            app.testCount = 1;
            cla(app.UIAxes);
        end

        % Button pushed function: CrearClaseButton
        function CrearClaseButtonPushed(app, event)
            x = app.CoordenadaXEditField.Value;
            y = app.CoordenadaYEditField.Value;
            z = app.CoordenadaZEditField.Value;
            r = app.DispersinEditField.Value;
            app.CreateClass(app.classIndex, x, y, z, r);
            app.classIndex = app.classIndex + 1;
            if app.classIndex > app.classes
                scatter3(app.UIAxes, app.means(1, :), app.means(2, :), app.means(3, :), 'filled', 'DisplayName', 'Medias');
                legend(app.UIAxes, 'show');
                app.EnableClassesGroup();
                app.DisableClassGroup();
                app.EnableTestGroup();
                return;
            end
        end

        % Button pushed function: ProbarButton
        function ProbarButtonPushed(app, event)
            x = app.XPruebaEditField.Value;
            y = app.YPruebaEditField.Value;
            z = app.ZPruebaEditField.Value;
            app.TestVector(x, y, z);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'App de Clasificadores')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.CoordenadaXEditFieldLabel = uilabel(app.UIFigure);
            app.CoordenadaXEditFieldLabel.Text = 'Coordenada X';
            app.CoordenadaXEditField = uieditfield(app.UIFigure, 'numeric');
            app.CoordenadaXEditField.Enable = 'off';
            app.CoordenadaYEditFieldLabel = uilabel(app.UIFigure);
            app.CoordenadaYEditFieldLabel.Text = 'Coordenada Y';
            app.CoordenadaYEditField = uieditfield(app.UIFigure, 'numeric');
            app.CoordenadaYEditField.Enable = 'off';
            app.DispersinEditFieldLabel = uilabel(app.UIFigure);
            app.DispersinEditFieldLabel.Text = 'Dispersión';
            app.DispersinEditField = uieditfield(app.UIFigure, 'numeric');
            app.DispersinEditField.Limits = [0 Inf];
            app.DispersinEditField.Enable = 'off';
            app.NmerodeClasesEditFieldLabel = uilabel(app.UIFigure);
            app.NmerodeClasesEditFieldLabel.Text = 'Número de Clases';
            app.NmerodeClasesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NmerodeClasesEditField.Limits = [1 Inf];
            app.NmerodeClasesEditField.RoundFractionalValues = 'on';
            app.NmerodeClasesEditField.Value = 1;
            app.CrearButton = uibutton(app.UIFigure, 'push');
            app.CrearButton.ButtonPushedFcn = createCallbackFcn(app, @CrearButtonPushed, true);
            app.CrearButton.Text = 'Crear';
            app.CrearClaseButton = uibutton(app.UIFigure, 'push');
            app.CrearClaseButton.ButtonPushedFcn = createCallbackFcn(app, @CrearClaseButtonPushed, true);
            app.CrearClaseButton.Enable = 'off';
            app.CrearClaseButton.Text = 'Crear Clase';
            app.XPruebaEditFieldLabel = uilabel(app.UIFigure);
            app.XPruebaEditFieldLabel.Text = 'X Prueba';
            app.XPruebaEditField = uieditfield(app.UIFigure, 'numeric');
            app.XPruebaEditField.Enable = 'off';
            app.YPruebaEditFieldLabel = uilabel(app.UIFigure);
            app.YPruebaEditFieldLabel.Text = 'Y Prueba';
            app.YPruebaEditField = uieditfield(app.UIFigure, 'numeric');
            app.YPruebaEditField.Enable = 'off';
            app.MtodoListBoxLabel = uilabel(app.UIFigure);
            app.MtodoListBoxLabel.Text = 'Método';
            app.MtodoListBox = uilistbox(app.UIFigure);
            app.MtodoListBox.Items = {'Distancia Euclideana', 'Mahalanobis', 'Máxima Probabilidad'};
            app.MtodoListBox.ItemsData = {'1', '2', '3'};
            app.MtodoListBox.Value = '1';
            app.MiembrosporClaseEditFieldLabel = uilabel(app.UIFigure);
            app.MiembrosporClaseEditFieldLabel.Text = 'Miembros por Clase';
            app.MiembrosporClaseEditField = uieditfield(app.UIFigure, 'numeric');
            app.MiembrosporClaseEditField.Limits = [1 Inf];
            app.MiembrosporClaseEditField.RoundFractionalValues = 'on';
            app.MiembrosporClaseEditField.Value = 1;
            app.ProbarButton = uibutton(app.UIFigure, 'push');
            app.ProbarButton.ButtonPushedFcn = createCallbackFcn(app, @ProbarButtonPushed, true);
            app.ProbarButton.Enable = 'off';
            app.ProbarButton.Text = 'Probar';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.ZPruebaEditFieldLabel = uilabel(app.UIFigure);
            app.ZPruebaEditFieldLabel.Text = 'Z Prueba';
            app.ZPruebaEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZPruebaEditField.Enable = 'off';
            app.CoordenadaZEditFieldLabel = uilabel(app.UIFigure);
            app.CoordenadaZEditFieldLabel.Text = 'Coordenada Z';
            app.CoordenadaZEditField = uieditfield(app.UIFigure, 'numeric');
            app.CoordenadaZEditField.Enable = 'off';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Clase'; 'Distancia'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
        end
    end

    methods (Access = public)

        % Construct app
        function app = classifierApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end